classdef two_tank_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Paneldecontrol_2               matlab.ui.container.Panel
        GridLayout_2                   matlab.ui.container.GridLayout
        BMotorSwitch                   matlab.ui.control.ToggleSwitch
        MOTORBANDASwitchLabel          matlab.ui.control.Label
        DMotorwitch                    matlab.ui.control.ToggleSwitch
        MOTORDISCOSwitchLabel          matlab.ui.control.Label
        Ret3Switch                     matlab.ui.control.ToggleSwitch
        RETEN3SwitchLabel              matlab.ui.control.Label
        Ret2Switch                     matlab.ui.control.ToggleSwitch
        RETEN2SwitchLabel              matlab.ui.control.Label
        Ret1Switch                     matlab.ui.control.ToggleSwitch
        RETEN1SwitchLabel              matlab.ui.control.Label
        Electro2Switch                 matlab.ui.control.ToggleSwitch
        ELECTRO2SwitchLabel            matlab.ui.control.Label
        Electro1Switch                 matlab.ui.control.ToggleSwitch
        ELECTRO1SwitchLabel            matlab.ui.control.Label
        Servo3Switch                   matlab.ui.control.ToggleSwitch
        SERVO3SwitchLabel              matlab.ui.control.Label
        Servo2Switch                   matlab.ui.control.ToggleSwitch
        SERVO2SwitchLabel              matlab.ui.control.Label
        Servo1Switch                   matlab.ui.control.ToggleSwitch
        SERVO1SwitchLabel              matlab.ui.control.Label
        EmergSwitch                    matlab.ui.control.ToggleSwitch
        EMERGENCIASwitchLabel          matlab.ui.control.Label
        StopSwitch                     matlab.ui.control.ToggleSwitch
        DETENERSwitchLabel             matlab.ui.control.Label
        StartSwitch                    matlab.ui.control.ToggleSwitch
        INICIARSwitchLabel             matlab.ui.control.Label
        Messages                       matlab.ui.control.TextArea
        MensajesTextAreaLabel          matlab.ui.control.Label
        Paneldeconexin                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        DownloadButton                 matlab.ui.control.Button
        SendFileButton                 matlab.ui.control.Button
        UploadButton                   matlab.ui.control.Button
        StartPLCButton                 matlab.ui.control.StateButton
        ConnectRPi                     matlab.ui.control.StateButton
        Image                          matlab.ui.control.Image
        ActuadoresbandadeprocesoPanel  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RET1                           matlab.ui.control.Lamp
        Retenedor1Label                matlab.ui.control.Label
        RET2                           matlab.ui.control.Lamp
        Retenedor2Label                matlab.ui.control.Label
        RET3                           matlab.ui.control.Lamp
        MOTORB                         matlab.ui.control.Lamp
        MotorbandaLabel                matlab.ui.control.Label
        MOTORD                         matlab.ui.control.Lamp
        MotordiscoLabel                matlab.ui.control.Label
        Retenedor3Label                matlab.ui.control.Label
        SensoresPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Tank1Level                     matlab.ui.control.LinearGauge
        Tank2Level                     matlab.ui.control.LinearGauge
        Infr3                          matlab.ui.control.Lamp
        Retenedor3Label_4              matlab.ui.control.Label
        Infr2                          matlab.ui.control.Lamp
        Retenedor3Label_3              matlab.ui.control.Label
        Infr1                          matlab.ui.control.Lamp
        Retenedor3Label_2              matlab.ui.control.Label
        Tank2EditField                 matlab.ui.control.NumericEditField
        Tanque2cmLabel                 matlab.ui.control.Label
        Tank1EditField                 matlab.ui.control.NumericEditField
        Tanque1cmLabel                 matlab.ui.control.Label
        ActuadorescontroldenivelPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SV1                            matlab.ui.control.Lamp
        Servovlvula1Label              matlab.ui.control.Label
        SV2                            matlab.ui.control.Lamp
        Servovlvula2Label              matlab.ui.control.Label
        EV2                            matlab.ui.control.Lamp
        Electrovlvula2LampLabel        matlab.ui.control.Label
        EV1                            matlab.ui.control.Lamp
        Electrovlvula1LampLabel        matlab.ui.control.Label
        SV3                            matlab.ui.control.Lamp
        Servovlvula3LampLabel          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        user % username
        myraspi % raspberry pi object
        modbusServer % modbus server object
        Timer % timer send user connection status

        % Control panel
        StartReg = 1025 % register for START button
        StopReg = 1026 % register for STOP button
        EmergReg = 1027 % register for EMERGENCY button

        % Communication
        serialPort3 = 'COM5' % serial port to notify raspberry pi availability
        serialDevice3 = '' % serial port object
        tcpPort1 = 31000 % tcp port for sending client's commands (PCL program)
        tcpClient1 % tcp client 1
        tcpPort2 = 5555 % tcp port for monitoring system variables
        tcpClient2 % tcp client 2

        % Flags
        RPiAvailable = '0'
        RPiConnection = '0' % '1' if raspberry pi connection has been established
        uploadedFile % name of uploaded file
        uploadedPath = '' % path of uploaded file
        PLCRunning = '0' % '1' if PLC is running
        FileSent = '0' % '1' if file has been sent to the Raspberry Pi

        % Plots and GUI
        dataSize = 5 % number of readings to be made before updating GUI
        PlotLine % line to plot tank 1 liquid level
        PlotLine2 % line to plot  tank 2 liquid level
        Tank1Data % variable to store tank 1 liquid levels
        Tank2Data % variable to store tank 2 liquid levels
        selectedVar = 'LED 1'
        xlen
    end
    methods (Access = private)
        %% Callbacks
        function readSystemVariables(app, src, ~)
            % read variables from raspberry pi through modbus protocol
            if src.NumBytesAvailable>0
                try
                    data = reshape(read(src, app.dataSize*9, 'double'), 9, 5);
                    % Update figures
                    app.Tank1Data = app.updateLinePlot(app.Tank1Data, data(8, :));
                    app.Tank2Data = app.updateLinePlot(app.Tank2Data, data(9, :));
                    app.PlotLine.YData = app.Tank1Data;
                    app.PlotLine2.YData = app.Tank2Data;
                    % Update LEDs
                    ev1_state = data(1, end);
                    ev2_state = data(2, end);
                    sv1_state = data(3, end);
                    sv2_state = data(4, end);
                    sv3_state = data(5, end);
                    app.turnOnOffLED(app.EV1, ev1_state, 'green');
                    app.turnOnOffLED(app.EV2, ev2_state, 'green');
                    app.turnOnOffLED(app.SV1, sv1_state, 'green');
                    app.turnOnOffLED(app.SV2, sv2_state, 'green');
                    app.turnOnOffLED(app.SV3, sv3_state, 'green');
                    % Update tank levels
                    app.Tank1Level.Value = data(8, end);
                    app.Tank1EditField.Value = data(8, end);
                    app.Tank2Level.Value = data(9, end);
                    app.Tank2EditField.Value = data(9, end);
                catch me
                    app.Messages.Value = me.message;
                    app.SystemInfo.Text = me.message;
                end
            end
        end
        function updateRPiStatus(app, src, ~)
            % Enable/disable RPi connection button when raspberry pi
            % availability changes
            if src.NumBytesAvailable>0
                msg = read(src, src.NumBytesAvailable, 'char');
                msg = split(msg, '|');
                app.RPiAvailable = msg{1};
                if strcmp(app.RPiAvailable, '1')&&(~strcmp(msg{2}, app.user)) % An user is connected to RPi
                    app.ConnectRPi.Enable = 'off';
                else % An user has disconnected from RPi
                    app.ConnectRPi.Enable = 'on';
                end
            end
        end
        %% Timer functions
        function TimerFcn(app, ~, ~)
            % Send user connection confirmation through tcp port
            writeline(app.tcpClient1,  ['connected', '|', '-']);
        end
        %% Helper functions 
        function turnOnOffLED(app, led, state, color)
            if state==1
                led.Color = color;
            else
                led.Color = 'black';
            end
        end
        function updateControlLEDs(app, colors)
            app.StartButton.BackgroundColor = colors{1};
            app.StopButton.BackgroundColor = colors{2};
            app.EmergButton.BackgroundColor = colors{3};
        end
        function lineplot = updateLinePlot(app, lineplot, data)
              lineplot = circshift(lineplot, app.dataSize);
              lineplot(1:app.dataSize) = data;
        end
        function enableControlButtons(app, state)
            % Enable buttons
            app.StartButton.Enable = state;
            app.StopButton.Enable = state;
            app.EmergButton.Enable = state;
            % Enable switches
            app.StartSwitch.Enable = state;
            app.StopSwitch.Enable = state;
            app.EmergSwitch.Enable = state;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Messages.Value = 'Iniciando...';
            % Get user information
            try
                log = compiler.UserInfo();
                app.user = log.UserID;
                app.UserInfo.Text = strcat('Usuario:', {' '}, app.user);
            catch me
                app.Messages.Value = me.message;
                app.SystemInfo.Text = me.message;
            end
            % Plots
            disableDefaultInteractivity(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'off';
                % Configure x- and y- axis
            app.xlen = 500;
            app.Tank1Data = zeros(1, app.xlen+1);
            app.Tank2Data = zeros(1, app.xlen+1);
            app.UIAxes.XLim = [0 app.xlen];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 20];
                % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:app.xlen, zeros(1, app.xlen+1));
            app.PlotLine2 = plot(app.UIAxes, 0:app.xlen, zeros(1, app.xlen+1));
            legend(app.UIAxes, 'Tanque 1', 'Tanque 2');
            % Timers
            app.Timer = timer( ...
                "ExecutionMode", 'fixedRate', ...
                'Period', 60, ...
                'BusyMode', 'queue', ...
                'StartDelay', 0.1, ...
                'TimerFcn', @app.TimerFcn);
            stop(app.Timer);
            % Serial port device
            try 
                app.serialDevice3 = serialport('COM5', 921600);
                configureTerminator(app.serialDevice3, 'LF');
                configureCallback(app.serialDevice3, 'byte', 1, @app.updateRPiStatus);
            catch me
                app.Messages.Value = me.message;
                return;
            end
            % Check raspberry pi availability
                % Create tcp client
            try
                client = tcpclient('localhost', 30000, 'Timeout', 15);
                app.RPiAvailable = writeread(client, ['check','|', '']);
                if strcmp(app.RPiAvailable, '0') % RPi is available 
                    app.ConnectRPi.Enable = 'on';
                end
            catch me
                app.SystemInfo.Text = me.message;
                app.ConnectLabel.Text = 'No se puedo iniciar comuniación TCP';
                app.Messages.Value = ['No se pudo iniciar comunicación TCP.' ...
                    'Verifique que el script de MATLAB se esté ejecuntando'];
                return;
            end
            pause(2.5);
            app.Messages.Value = 'Inicialización éxitosa';
        end

        % Value changed function: ConnectRPi
        function ConnectRPiValueChanged(app, event)
            value = app.ConnectRPi.Value;
            % Check if PLC is running
            if strcmp(app.PLCRunning, '1')
                %app.ConnectLabel.Text = 'Programa PLC está corriendo';
                app.Messages.Value = ['El programa PLC está ejecutandose.' ...
                    'Detenga el programa antes de desconectarse'];
                app.ConnectRPi.Value = ~value;
                return
            end
            % Create tcp client
            try
                %app.ConnectLabel.Text = 'Iniciando comunicación TCP...';
                app.Messages.Value = 'Iniciando comunicación TCP...';
                client = tcpclient('localhost', 30000, 'Timeout', 30);
                pause(0.5);
            catch me
                app.SystemInfo.Text = me.message;
                %app.ConnectLabel.Text = 'No se puedo iniciar comuniación TCP';
                app.Messages.Value = 'No se pudo iniciar comunicación TCP';
                app.ConnectRPi.Value = ~value;
                return;
            end
            % Connect to raspberry pi
            if value
                % raspberry pi is available
                if strcmp(app.RPiAvailable, '0')
                    try 
                        %app.ConnectLabel.Text = 'Conectando RPi...';
                        app.Messages.Value = 'Conectando Raspberry Pi';
                        app.RPiConnection = writeread(client, ['connect', '|', app.user]);
                        pause(0.5);
                    catch me
                        %app.SystemInfo.Text = me.message;
                        app.Messages.Value = 'No se pudo enviar comando para conectar Raspberry Pi';
                        %app.ConnectLabel.Text = "No se pudo enviar comando para conectar RPi";
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                    % User is connected to RPi
                    if strcmp(app.RPiConnection, '1')
                        app.ConnectRPi.BackgroundColor = [0.95,0.25,0.25];
                        app.ConnectRPi.Text = 'Desconectar RPi';
                        %app.ConnectLabel.Text = '';
                        app.Messages.Value = 'Conectado a Raspberry Pi';
                        % Create tcp clients
                        app.tcpClient2 = tcpclient('localhost', app.tcpPort2, 'Timeout', 30);
                        app.tcpClient1 = tcpclient('localhost', app.tcpPort1, 'Timeout', 30);
                        configureTerminator(app.tcpClient1, 'LF');
                        % Start timer
                        start(app.Timer);
                    else
                        %app.ConnectLabel.Text = "No se pudo conectar RPi";
                        app.Messages.Value = 'No se pudo conectar a la Raspberry Pi';
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                % raspberry pi is not available
                else
                    %app.ConnectLabel.Text = "RPi no está disponible";
                    app.Messages.Value = 'Raspberry Pi no está disponible';
                    app.ConnectRPi.Value = ~value;
                end
            % Disconnect from raspberry pi
            else
                try
                    %app.ConnectLabel.Text = 'Desconectando...';
                    app.Messages.Value = 'Desconectando...';
                    app.RPiConnection = writeread(client, ['disconnect', '|', app.user]);
                    pause(0.5);
                catch me
                    %app.ConnectLabel.Text = "No se puedo enviar comando de desconexión";
                    app.Messages.Value = 'No se pudo enviar comando de desconexión';
                    %app.SystemInfo.Text = me.message;
                    return;
                end
                app.myraspi = []; % delete raspberry pi object
                app.tcpClient1 = []; 
                app.tcpClient2 = [];
                app.ConnectRPi.BackgroundColor = [0.39,0.83,0.07];
                app.ConnectRPi.Text = 'Conectar RPi';
                app.ConnectLabel.Text = '';
                app.StartPLCButton.Enable = 'off';
                stop(app.Timer); % stop timer
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.FileLabel.Text = '';
            [app.uploadedFile, app.uploadedPath] = uigetfile();
            if ischar(app.uploadedFile)
                app.FileLabel.Text = strcat(app.uploadedFile, ...
                    ' Archivo cargado correctamente');
            else
                app.StartPLCButton.Enable = "off";
            end
        end

        % Value changed function: StartPLCButton
        function StartPLCButtonValueChanged(app, event)
            value = app.StartPLCButton.Value;
            % Check raspberry pi connection
            if strcmp(app.RPiConnection, '0')
                %app.PLCLabel.Text = "No estás conectado a la RPi";
                app.Messages.Value = 'No está conectado a la Raspberry Pi';
                app.StartPLCButton.Value = ~value;
                return
            end
            % Check if file has been sent
            if strcmp(app.FileSent, '0')
                %app.PLCLabel.Text = "No se ha enviado ningún archivo";
                app.Messages.Value = 'No se ha enviado ningún archivo';
                app.StartPLCButton.Value = ~value;
                return
            end
            % Initialize PLC program
            if value
                try
                    %app.PLCLabel.Text = 'Iniciando PLC...';
                    app.Messages.Value = 'Iniciando PLC...';
                    app.PLCRunning = writeread(app.tcpClient1, ['start', '|', '-']);
                catch me
                    app.SystemInfo.Text = me.message;
                    %app.PLCLabel.Text = "No fue posible enviar comando para iniciar PLC";
                    app.Messages.Value = 'No fue posible enviar comando para inciar PLC';
                    app.StartPLCButton.Value = ~value;
                    return
                end
                % PLC is running
                if strcmp(app.PLCRunning, '1')
                    % Clear figure
                    app.PlotLine.YData = zeros(1, app.xlen+1);
                    app.Tank1Data = zeros(1, app.xlen+1);
                    app.Tank2Data = zeros(1, app.xlen+1);
                    app.StartPLCButton.BackgroundColor = [0.95,0.25,0.25];
                    app.StartPLCButton.Text = 'Stop PCL';
                    app.PLCLabel.Text = '';
                    app.enableControlButtons('on');
                    writeline(app.tcpClient1, 'running|-');
                    % Configure serial port callback
                    try
                        configureCallback(app.tcpClient2, 'byte', 1, @app.readSystemVariables);
                    catch me
                        %app.SystemInfo.Text = me.message;
                        app.Messages.Value = me.message;
                    end
                % PLC is not running
                else 
                    app.StartPLCButton.Text = 'Iniciar PLC';
                    app.StartPLCButton.Value = ~value;
                    %app.PLCLabel.Text = "No se pudo iniciar programa PLC";
                    app.Messages.Value = 'No se pudo iniciar programa PLC';
                end
            % Stopping PLC program
            else
                app.PLCLabel.Text = 'Deteniendo programa PLC...';
                configureCallback(app.tcpClient2, 'off'); % shut down serial port callback             
                app.PLCRunning = writeread(app.tcpClient1, ['stop', '|', pwd]);
                % PLC program stopped
                if strcmp(app.PLCRunning, '0')
                    app.StartPLCButton.BackgroundColor = [0.39,0.83,0.07];
                    app.StartPLCButton.Text = 'Iniciar PLC';
                    app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.96,0.96,0.96]});
                    app.enableControlButtons('off');
                    app.PLCLabel.Text = '';
                % PLC program couldn't be stopped
                else
                    %app.PLCLabel.Text = "No se pudo detener programa PLC";
                    app.Messages.Value = 'No se pudo detener programa PLC';
                    app.StartPLCButton.Value = ~value;
                    configureCallback(app.tcpClient2, 'byte', 1, @app.readSystemVariables);
                end
            end
        end

        % Callback function
        function KnobValueChanged(app, event)
                
        end

        % Callback function
        function EmergButtonPushed(app, event)
            %app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.95,0.25,0.25]});
            %write(app.tcpClient2, [0, 0, 1], 'uint8');
        end

        % Callback function
        function StartButtonPushed(app, event)
            %app.updateControlLEDs({[0.39,0.83,0.07], [0.96,0.96,0.96], [0.96,0.96,0.96]});
            %write(app.tcpClient2, [1, 0, 0], 'uint8');
        end

        % Callback function
        function StopButtonPushed(app, event)
            %app.updateControlLEDs({[0.96,0.96,0.96], [1.00,0.41,0.16], [0.96,0.96,0.96]});
            %write(app.tcpClient2, [0, 1, 0], 'uint8');            
        end

        % Callback function
        function CheckConnectionButtonPushed(app, event)

        end

        % Button pushed function: SendFileButton
        function SendFileButtonPushed(app, event)
            % Check if user is connected to raspberry pi
            if strcmp(app.RPiConnection, '0')
                %app.FileLabel.Text = "No estás conectado a la RPi";
                app.Messages.Value = 'No está conectado a la Raspberry Pi';
                return
            end
            % Check if file has been sent
            if ~ischar(app.uploadedFile)
                %app.FileLabel.Text = "No se ha cargado ningún archivo";
                app.Messages.Value = 'No se ha cargado ningún archivo';
                return
            end
            % Check if PLC is running 
            if strcmp(app.PLCRunning, '1')
                %app.FileLabel.Text = 'Programa PLC está corriendo';
                app.Messages.Value = ['El programa PLC está corriendo.' ...
                    'Detenga el programa antes de enviar un nuevo archivo'];
                return
            end
            % Enviar programa PLC a la raspberry pi
            try
                %app.FileLabel.Text = 'Enviando programa PLC...';
                app.Messages.Values = 'Enviando programa PLC...';
                app.FileSent = writeread(app.tcpClient1, [fullfile(app.uploadedPath, app.uploadedFile), '|', app.uploadedFile]);
                %app.PLCLabel.Text = '';
                %app.FileLabel.Text = '';
                if strcmp(app.FileSent, '1')
                    app.StartPLCButton.Enable = "on";
                end
            catch me
                %app.FileLabel.Text = "Ocurrió un error. Verifica el nombre del archivo.";
                %app.SystemInfo.Text = me.message;
                app.Messages.Value = 'Ocurrió un error. Verifica el nombre del archivo';
                return
            end
        end

        % Callback function
        function VariableDropDownValueChanged(app, event)
            app.selectedVar = app.VariableDropDown.Value;
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            try
            data = readmatrix(fullfile(pwd, 'results.xlsx'));
            [file, path] = uiputfile('download.xlsx');
            resultFilePath = fullfile(path, file);
            writematrix(data, resultFilePath);
            catch me
                app.SystemInfo.Text = me.message;
            end
        end

        % Value changed function: EmergSwitch, StartSwitch, StopSwitch
        function ControlSwitchValuesChanged(app, event)
            startSwitchValue = app.StartSwitch.Value;
            stopSwitchValue = app.StopSwitch.Value;
            emergSwitchValue = app.EmergSwitch.Value;
            %write(app.tcpClient2, [0, 1, 0], 'uint8');
            display(startSwitchValue);
            display(stopSwitchValue);
            display(emergSwitchValue);
            display(event.Source);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Nivel de líquido en los tanques')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Altura (cm)')
            zlabel(app.UIAxes, 'Z')
            app.ActuadorescontroldenivelPanel = uipanel(app.UIFigure);
            app.ActuadorescontroldenivelPanel.Title = 'Actuadores control de nivel';
            app.GridLayout2 = uigridlayout(app.ActuadorescontroldenivelPanel);
            app.Servovlvula3LampLabel = uilabel(app.GridLayout2);
            app.Servovlvula3LampLabel.Text = 'Servoválvula 3';
            app.SV3 = uilamp(app.GridLayout2);
            app.Electrovlvula1LampLabel = uilabel(app.GridLayout2);
            app.Electrovlvula1LampLabel.Text = 'Electroválvula 1';
            app.EV1 = uilamp(app.GridLayout2);
            app.Electrovlvula2LampLabel = uilabel(app.GridLayout2);
            app.Electrovlvula2LampLabel.Text = 'Electroválvula 2';
            app.EV2 = uilamp(app.GridLayout2);
            app.Servovlvula2Label = uilabel(app.GridLayout2);
            app.Servovlvula2Label.Text = 'Servoválvula 2';
            app.SV2 = uilamp(app.GridLayout2);
            app.Servovlvula1Label = uilabel(app.GridLayout2);
            app.Servovlvula1Label.Text = 'Servoválvula 1';
            app.SV1 = uilamp(app.GridLayout2);
            app.SensoresPanel = uipanel(app.UIFigure);
            app.SensoresPanel.Title = 'Sensores';
            app.GridLayout4 = uigridlayout(app.SensoresPanel);
            app.Tanque1cmLabel = uilabel(app.GridLayout4);
            app.Tanque1cmLabel.Text = 'Tanque 1 (cm)';
            app.Tank1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Tank1EditField.Editable = 'off';
            app.Tanque2cmLabel = uilabel(app.GridLayout4);
            app.Tanque2cmLabel.Text = 'Tanque 2 (cm)';
            app.Tank2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Tank2EditField.Editable = 'off';
            app.Retenedor3Label_2 = uilabel(app.GridLayout4);
            app.Retenedor3Label_2.Text = 'Infrarrojo 1';
            app.Infr1 = uilamp(app.GridLayout4);
            app.Retenedor3Label_3 = uilabel(app.GridLayout4);
            app.Retenedor3Label_3.Text = 'Infrarrojo 2';
            app.Infr2 = uilamp(app.GridLayout4);
            app.Retenedor3Label_4 = uilabel(app.GridLayout4);
            app.Retenedor3Label_4.Text = 'Infrarrojo 3';
            app.Infr3 = uilamp(app.GridLayout4);
            app.Tank2Level = uigauge(app.GridLayout4, 'linear');
            app.Tank2Level.Limits = [0 17];
            app.Tank1Level = uigauge(app.GridLayout4, 'linear');
            app.Tank1Level.Limits = [0 17];
            app.ActuadoresbandadeprocesoPanel = uipanel(app.UIFigure);
            app.ActuadoresbandadeprocesoPanel.Title = 'Actuadores banda de proceso';
            app.GridLayout3 = uigridlayout(app.ActuadoresbandadeprocesoPanel);
            app.Retenedor3Label = uilabel(app.GridLayout3);
            app.Retenedor3Label.Text = 'Retenedor 3';
            app.MotordiscoLabel = uilabel(app.GridLayout3);
            app.MotordiscoLabel.Text = 'Motor disco';
            app.MOTORD = uilamp(app.GridLayout3);
            app.MotorbandaLabel = uilabel(app.GridLayout3);
            app.MotorbandaLabel.Text = 'Motor banda';
            app.MOTORB = uilamp(app.GridLayout3);
            app.RET3 = uilamp(app.GridLayout3);
            app.Retenedor2Label = uilabel(app.GridLayout3);
            app.Retenedor2Label.Text = 'Retenedor 2';
            app.RET2 = uilamp(app.GridLayout3);
            app.Retenedor1Label = uilabel(app.GridLayout3);
            app.Retenedor1Label.Text = 'Retenedor 1';
            app.RET1 = uilamp(app.GridLayout3);
            app.Paneldeconexin = uipanel(app.UIFigure);
            app.Paneldeconexin.Title = 'Panel de conexión';
            app.GridLayout5 = uigridlayout(app.Paneldeconexin);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'MaIE.png');
            app.ConnectRPi = uibutton(app.GridLayout5, 'state');
            app.ConnectRPi.ValueChangedFcn = createCallbackFcn(app, @ConnectRPiValueChanged, true);
            app.ConnectRPi.Enable = 'off';
            app.ConnectRPi.Text = 'Conectar RPi';
            app.StartPLCButton = uibutton(app.GridLayout5, 'state');
            app.StartPLCButton.ValueChangedFcn = createCallbackFcn(app, @StartPLCButtonValueChanged, true);
            app.StartPLCButton.Enable = 'off';
            app.StartPLCButton.Text = 'Iniciar PLC';
            app.UploadButton = uibutton(app.GridLayout5, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'icons', 'excel_download.png');
            app.UploadButton.Text = '';
            app.SendFileButton = uibutton(app.GridLayout5, 'push');
            app.SendFileButton.ButtonPushedFcn = createCallbackFcn(app, @SendFileButtonPushed, true);
            app.SendFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'upload.png');
            app.SendFileButton.Text = '';
            app.DownloadButton = uibutton(app.GridLayout5, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Icon = fullfile(pathToMLAPP, 'icons', 'send_file.png');
            app.DownloadButton.Text = '';
            app.MensajesTextAreaLabel = uilabel(app.UIFigure);
            app.MensajesTextAreaLabel.Text = 'Mensajes';
            app.Messages = uitextarea(app.UIFigure);
            app.Messages.Editable = 'off';
            app.Paneldecontrol_2 = uipanel(app.UIFigure);
            app.Paneldecontrol_2.Title = 'Panel de control';
            app.GridLayout_2 = uigridlayout(app.Paneldecontrol_2);
            app.INICIARSwitchLabel = uilabel(app.GridLayout_2);
            app.INICIARSwitchLabel.Text = 'INICIAR';
            app.StartSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.StartSwitch.Items = {'0', '1'};
            app.StartSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.StartSwitch.Value = '0';
            app.DETENERSwitchLabel = uilabel(app.GridLayout_2);
            app.DETENERSwitchLabel.Text = 'DETENER';
            app.StopSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.StopSwitch.Items = {'0', '1'};
            app.StopSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.StopSwitch.Value = '0';
            app.EMERGENCIASwitchLabel = uilabel(app.GridLayout_2);
            app.EMERGENCIASwitchLabel.Text = 'EMERGENCIA';
            app.EmergSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.EmergSwitch.Items = {'0', '1'};
            app.EmergSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValuesChanged, true);
            app.EmergSwitch.Value = '0';
            app.SERVO1SwitchLabel = uilabel(app.GridLayout_2);
            app.SERVO1SwitchLabel.Enable = 'off';
            app.SERVO1SwitchLabel.Text = 'SERVO. 1';
            app.Servo1Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Servo1Switch.Items = {'0', '1'};
            app.Servo1Switch.Enable = 'off';
            app.Servo1Switch.Value = '0';
            app.SERVO2SwitchLabel = uilabel(app.GridLayout_2);
            app.SERVO2SwitchLabel.Enable = 'off';
            app.SERVO2SwitchLabel.Text = 'SERVO. 2';
            app.Servo2Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Servo2Switch.Items = {'0', '1'};
            app.Servo2Switch.Enable = 'off';
            app.Servo2Switch.Value = '0';
            app.SERVO3SwitchLabel = uilabel(app.GridLayout_2);
            app.SERVO3SwitchLabel.Enable = 'off';
            app.SERVO3SwitchLabel.Text = 'SERVO. 3';
            app.Servo3Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Servo3Switch.Items = {'0', '1'};
            app.Servo3Switch.Enable = 'off';
            app.Servo3Switch.Value = '0';
            app.ELECTRO1SwitchLabel = uilabel(app.GridLayout_2);
            app.ELECTRO1SwitchLabel.Enable = 'off';
            app.ELECTRO1SwitchLabel.Text = 'ELECTRO. 1';
            app.Electro1Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Electro1Switch.Items = {'0', '1'};
            app.Electro1Switch.Enable = 'off';
            app.Electro1Switch.Value = '0';
            app.ELECTRO2SwitchLabel = uilabel(app.GridLayout_2);
            app.ELECTRO2SwitchLabel.Enable = 'off';
            app.ELECTRO2SwitchLabel.Text = 'ELECTRO. 2';
            app.Electro2Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Electro2Switch.Items = {'0', '1'};
            app.Electro2Switch.Enable = 'off';
            app.Electro2Switch.Value = '0';
            app.RETEN1SwitchLabel = uilabel(app.GridLayout_2);
            app.RETEN1SwitchLabel.Enable = 'off';
            app.RETEN1SwitchLabel.Text = 'RETEN. 1';
            app.Ret1Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret1Switch.Items = {'0', '1'};
            app.Ret1Switch.Enable = 'off';
            app.Ret1Switch.Value = '0';
            app.RETEN2SwitchLabel = uilabel(app.GridLayout_2);
            app.RETEN2SwitchLabel.Enable = 'off';
            app.RETEN2SwitchLabel.Text = 'RETEN. 2';
            app.Ret2Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret2Switch.Items = {'0', '1'};
            app.Ret2Switch.Enable = 'off';
            app.Ret2Switch.Value = '0';
            app.RETEN3SwitchLabel = uilabel(app.GridLayout_2);
            app.RETEN3SwitchLabel.Enable = 'off';
            app.RETEN3SwitchLabel.Text = 'RETEN. 3';
            app.Ret3Switch = uiswitch(app.GridLayout_2, 'toggle');
            app.Ret3Switch.Items = {'0', '1'};
            app.Ret3Switch.Enable = 'off';
            app.Ret3Switch.Value = '0';
            app.MOTORDISCOSwitchLabel = uilabel(app.GridLayout_2);
            app.MOTORDISCOSwitchLabel.Enable = 'off';
            app.MOTORDISCOSwitchLabel.Text = 'MOTOR DISCO';
            app.DMotorwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.DMotorwitch.Items = {'0', '1'};
            app.DMotorwitch.Enable = 'off';
            app.DMotorwitch.Value = '0';
            app.MOTORBANDASwitchLabel = uilabel(app.GridLayout_2);
            app.MOTORBANDASwitchLabel.Enable = 'off';
            app.MOTORBANDASwitchLabel.Text = 'MOTOR BANDA';
            app.BMotorSwitch = uiswitch(app.GridLayout_2, 'toggle');
            app.BMotorSwitch.Items = {'0', '1'};
            app.BMotorSwitch.Enable = 'off';
            app.BMotorSwitch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_tank_lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end